classdef DataExport < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        FilePathPanel         matlab.ui.container.Panel
        DataPathButton        matlab.ui.control.Button
        LabelEditField        matlab.ui.control.Label
        DataPath              matlab.ui.control.EditField
        ExportPathButton      matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        ExportPath            matlab.ui.control.EditField
        FileNamePanel         matlab.ui.container.Panel
        FileListBox           matlab.ui.control.ListBox
        Label2                matlab.ui.control.Label
        FileNameTemplate      matlab.ui.control.EditField
        LabelDropDown         matlab.ui.control.Label
        FileDateSortDropDown  matlab.ui.control.DropDown
        FileRefreshButton     matlab.ui.control.Button
        FileExportPanel       matlab.ui.container.Panel
        ExportButton          matlab.ui.control.Button
        LabelDropDown2        matlab.ui.control.Label
        FileSourceDropDown    matlab.ui.control.DropDown
        LabelLamp             matlab.ui.control.Label
        StatusLamp            matlab.ui.control.Lamp
        Parallel              matlab.ui.control.CheckBox
        ErrorFile             matlab.ui.control.ListBox
    end


    properties (Access = public)
        FileFolder % dir of files
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            preference = NeuroAnalysis.Base.LoadVar('DataExport','DataExportPreference');
            if ~isempty(preference)
                app.DataPath.Value=preference.datapath;
                app.ExportPath.Value=preference.exportpath;
                app.FileNameTemplate.Value=preference.filenametemplate;
                app.FileDateSortDropDown.Value=preference.filedatesort;
                app.FileSourceDropDown.Value=preference.filesource;
                app.Parallel.Value = preference.parallel;
            end
            DataPathChanged(app);
        end

        % Button pushed function: DataPathButton
        function ChooseDataPath(app, event)
            hr = uigetdir('','Choose Data Path');
            if isa(hr,'char')
                app.DataPath.Value = hr;
                DataPathChanged(app);
            end
        end

        % Value changed function: DataPath
        function DataPathChanged(app, event)
            datapath = app.DataPath.Value;
            datanametemplate = app.FileNameTemplate.Value;
            fs = dir(fullfile(datapath,datanametemplate));
            fsname = arrayfun(@(i)i.name,fs,'UniformOutput',false);
            fsfolder = arrayfun(@(i)i.folder,fs,'UniformOutput',false);
            fsdatenum = arrayfun(@(i)i.datenum,fs);
            sortmethod = app.FileDateSortDropDown.Value;
            [~,sorti] = sort(fsdatenum,sortmethod);
            
            app.FileListBox.Items = fsname(sorti);
            app.FileFolder = fsfolder(sorti)';
        end

        % Value changed function: FileNameTemplate
        function FileNameTemplateChanged(app, event)
            DataPathChanged(app);
        end

        % Value changed function: FileDateSortDropDown
        function FileDateSortChanged(app, event)
            DataPathChanged(app);
        end

        % Button pushed function: ExportPathButton
        function ChooseExportPath(app, event)
            hr = uigetdir('','Choose Data Export Path');
            if isa(hr,'char')
                app.ExportPath.Value = hr;
            end
        end

        % Button pushed function: ExportButton
        function Export(app, event)
            exportpath = app.ExportPath.Value;
            if(exist(exportpath,'dir')~=7)
                mkdir(exportpath);
            end
            datasource = app.FileSourceDropDown.Value;
            filename = app.FileListBox.Value;
            [~,idx] = ismember(filename,app.FileListBox.Items);
            filefolder = app.FileFolder(idx);
            datapath = cellfun(@(x,y)fullfile(x,y),filefolder,filename,'UniformOutput',false);
            isparallel = app.Parallel.Value;
            if ~isempty(datapath)
                app.ErrorFile.Items={};
                
                app.StatusLamp.Color = [0.12,0.85,0.12];
                app.LabelLamp.Text = 'Running';
                results = NeuroAnalysis.IO.Export(datapath,exportpath,datasource,isparallel);
                app.StatusLamp.Color = [0.12,0.15,0.12];
                app.LabelLamp.Text = 'Idle';
                
                status = cellfun(@(x)x.status,results);
                if any(~status)
                    source = cellfun(@(x)x.source,results);
                    app.ErrorFile.Items = source(~status);
                end
            end
        end

        % Close request function: UIFigure
        function closerequestFcn(app, event)
            preference.datapath = app.DataPath.Value;
            preference.exportpath = app.ExportPath.Value;
            preference.filenametemplate = app.FileNameTemplate.Value;
            preference.filedatesort = app.FileDateSortDropDown.Value;
            preference.filesource = app.FileSourceDropDown.Value;
            preference.parallel = app.Parallel.Value;
            
            NeuroAnalysis.Base.SaveVar('DataExport',preference,'DataExportPreference');
            delete(app);
        end

        % Button pushed function: FileRefreshButton
        function FileRefreshButtonPushed(app, event)
            DataPathChanged(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'DataExport';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closerequestFcn, true);
            setAutoResize(app, app.UIFigure, true)
            app.FilePathPanel = uipanel(app.UIFigure);
            app.FilePathPanel.Title = 'File Path';
            app.DataPathButton = uibutton(app.FilePathPanel, 'push');
            app.DataPathButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseDataPath, true);
            app.DataPathButton.Text = '...';
            app.LabelEditField = uilabel(app.FilePathPanel);
            app.LabelEditField.Text = 'Data';
            app.DataPath = uieditfield(app.FilePathPanel, 'text');
            app.DataPath.ValueChangedFcn = createCallbackFcn(app, @DataPathChanged, true);
            app.ExportPathButton = uibutton(app.FilePathPanel, 'push');
            app.ExportPathButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseExportPath, true);
            app.ExportPathButton.Text = '...';
            app.Label = uilabel(app.FilePathPanel);
            app.Label.Text = 'Export';
            app.ExportPath = uieditfield(app.FilePathPanel, 'text');
            app.FileNamePanel = uipanel(app.UIFigure);
            app.FileNamePanel.Title = 'File Name';
            app.FileListBox = uilistbox(app.FileNamePanel);
            app.FileListBox.Items = {};
            app.FileListBox.Multiselect = 'on';
            app.FileListBox.Value = {};
            app.Label2 = uilabel(app.FileNamePanel);
            app.Label2.Text = 'Name';
            app.FileNameTemplate = uieditfield(app.FileNamePanel, 'text');
            app.FileNameTemplate.ValueChangedFcn = createCallbackFcn(app, @FileNameTemplateChanged, true);
            app.LabelDropDown = uilabel(app.FileNamePanel);
            app.LabelDropDown.Text = 'Modify Date';
            app.FileDateSortDropDown = uidropdown(app.FileNamePanel);
            app.FileDateSortDropDown.Items = {'Descend', 'Ascend'};
            app.FileDateSortDropDown.ValueChangedFcn = createCallbackFcn(app, @FileDateSortChanged, true);
            app.FileDateSortDropDown.Value = 'Descend';
            app.FileRefreshButton = uibutton(app.FileNamePanel, 'push');
            app.FileRefreshButton.ButtonPushedFcn = createCallbackFcn(app, @FileRefreshButtonPushed, true);
            app.FileRefreshButton.Icon = 'noun_refresh_cc.png';
            app.FileRefreshButton.Text = '';
            app.FileExportPanel = uipanel(app.UIFigure);
            app.FileExportPanel.Title = 'File Export';
            app.ExportButton = uibutton(app.FileExportPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @Export, true);
            app.ExportButton.Text = 'Export';
            app.LabelDropDown2 = uilabel(app.FileExportPanel);
            app.LabelDropDown2.Text = 'Source';
            app.FileSourceDropDown = uidropdown(app.FileExportPanel);
            app.FileSourceDropDown.Items = {'Ripple', 'Blackrock', 'Plexon'};
            app.FileSourceDropDown.Value = 'Ripple';
            app.LabelLamp = uilabel(app.FileExportPanel);
            app.LabelLamp.Text = 'Idle';
            app.StatusLamp = uilamp(app.FileExportPanel);
            app.Parallel = uicheckbox(app.FileExportPanel);
            app.Parallel.Text = 'Parallel Export';
            app.Parallel.Value = true;
            app.ErrorFile = uilistbox(app.FileExportPanel);
            app.ErrorFile.Items = {};
            app.ErrorFile.Multiselect = 'on';
            app.ErrorFile.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = DataExport()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end